classdef DQGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataQualityAssessmentApplicationUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DQATab                         matlab.ui.container.Tab
        LoadDataButton                 matlab.ui.control.Button
        OrigPlotButton                 matlab.ui.control.Button
        CalculatePartitionsButton      matlab.ui.control.Button
        SavePartitionsButton           matlab.ui.control.Button
        SelecttimeintervalLabel        matlab.ui.control.Label
        SelectTimeEditField            matlab.ui.control.EditField
        InPanel                        matlab.ui.container.Panel
        SelectInDropDownLabel          matlab.ui.control.Label
        SelectInDropDown               matlab.ui.control.DropDown
        OutPanel                       matlab.ui.container.Panel
        SelectOutDropDownLabel         matlab.ui.control.Label
        SelectOutDropDown              matlab.ui.control.DropDown
        OriginalUIAxes                 matlab.ui.control.UIAxes
        PartitionsUIAxes               matlab.ui.control.UIAxes
        InSettingsTab                  matlab.ui.container.Tab
        InConstraintsPanel             matlab.ui.container.Panel
        InLimValPanel                  matlab.ui.container.Panel
        LowerInLimValEditFieldLabel    matlab.ui.control.Label
        LowerInLimValEditField         matlab.ui.control.NumericEditField
        UpperInLimValEditFieldLabel    matlab.ui.control.Label
        UpperInLimValEditField         matlab.ui.control.NumericEditField
        SelectValueLimits              matlab.ui.control.CheckBox
        InCritValPanel                 matlab.ui.container.Panel
        InCritValEditField             matlab.ui.control.NumericEditField
        SelectCritValues               matlab.ui.control.CheckBox
        InLimVarPanel                  matlab.ui.container.Panel
        LowerInLimVarEditFieldLabel    matlab.ui.control.Label
        LowerInLimVarEditField         matlab.ui.control.NumericEditField
        UpperInLimVarEditFieldLabel    matlab.ui.control.Label
        UpperInLimVarEditField         matlab.ui.control.NumericEditField
        InmovvarkEditFieldLabel        matlab.ui.control.Label
        InmovvarkEditField             matlab.ui.control.NumericEditField
        SelectVarLimits                matlab.ui.control.CheckBox
        AddInConstraintsPanel          matlab.ui.container.Panel
        InAbruptChangeCheckBox         matlab.ui.control.CheckBox
        InFreezeCheckBox               matlab.ui.control.CheckBox
        InSaturatedloopCheckBox        matlab.ui.control.CheckBox
        SelectAddConstraints           matlab.ui.control.CheckBox
        ImplementConstraintsButton     matlab.ui.control.Button
        SetpSettingsTab                matlab.ui.container.Tab
        SetpPanel                      matlab.ui.container.Panel
        SetpButtonGroup                matlab.ui.container.ButtonGroup
        NoSetpButton                   matlab.ui.control.RadioButton
        YesSetpButton                  matlab.ui.control.RadioButton
        SelectSetpDropDownLabel        matlab.ui.control.Label
        SelectSetpDropDown             matlab.ui.control.DropDown
        SetpConstraintsPanel           matlab.ui.container.Panel
        SetpLimValPanel                matlab.ui.container.Panel
        LowerSetpLimValEditFieldLabel  matlab.ui.control.Label
        LowerSetpLimValEditField       matlab.ui.control.NumericEditField
        UpperSetpLimValEditFieldLabel  matlab.ui.control.Label
        UpperSetpLimValEditField       matlab.ui.control.NumericEditField
        SelectValueLimits_2            matlab.ui.control.CheckBox
        SetpCritValPanel               matlab.ui.container.Panel
        SetpCritValEditField           matlab.ui.control.NumericEditField
        SelectValueLimits_4            matlab.ui.control.CheckBox
        SetpLimVarPanel                matlab.ui.container.Panel
        LowerSetpLimVarEditFieldLabel  matlab.ui.control.Label
        LowerSetpLimVarEditField       matlab.ui.control.NumericEditField
        UpperSetpLimVarEditFieldLabel  matlab.ui.control.Label
        UpperSetpLimVarEditField       matlab.ui.control.NumericEditField
        SetpmovvarkEditFieldLabel      matlab.ui.control.Label
        SetpmovvarkEditField           matlab.ui.control.NumericEditField
        SelectValueLimits_3            matlab.ui.control.CheckBox
        AddSetpConstraintsPanel        matlab.ui.container.Panel
        SetpAbruptChangeCheckBox       matlab.ui.control.CheckBox
        SetpFreezeCheckBox             matlab.ui.control.CheckBox
        SetpSaturatedloopCheckBox      matlab.ui.control.CheckBox
        SelectValueLimits_5            matlab.ui.control.CheckBox
        ImplementConstraintsButton_2   matlab.ui.control.Button
        ParametersTab                  matlab.ui.container.Tab
        LaguerreParametersPanel        matlab.ui.container.Panel
        alpha1EditFieldLabel           matlab.ui.control.Label
        alpha1EditField                matlab.ui.control.NumericEditField
        NisEditFieldLabel              matlab.ui.control.Label
        NisEditField                   matlab.ui.control.NumericEditField
        DefaultsLaguerreButton         matlab.ui.control.Button
        ForgettingParametersPanel      matlab.ui.container.Panel
        lambdanyEditFieldLabel         matlab.ui.control.Label
        lambdanyEditField              matlab.ui.control.NumericEditField
        lambdamLEditFieldLabel         matlab.ui.control.Label
        lambdamLEditField              matlab.ui.control.NumericEditField
        lambdanLEditFieldLabel         matlab.ui.control.Label
        lambdanLEditField              matlab.ui.control.NumericEditField
        lambdamuEditFieldLabel         matlab.ui.control.Label
        lambdamuEditField              matlab.ui.control.NumericEditField
        lambdanuEditFieldLabel         matlab.ui.control.Label
        lambdanuEditField              matlab.ui.control.NumericEditField
        etaL1Label                     matlab.ui.control.Label
        etaL1EditField                 matlab.ui.control.NumericEditField
        etaYEditFieldLabel             matlab.ui.control.Label
        etaYEditField                  matlab.ui.control.NumericEditField
        etaCondEditFieldLabel          matlab.ui.control.Label
        etaCondEditField               matlab.ui.control.NumericEditField
        lambdaREditFieldLabel          matlab.ui.control.Label
        lambdaREditField               matlab.ui.control.NumericEditField
        etaChiEditFieldLabel           matlab.ui.control.Label
        etaChiEditField                matlab.ui.control.NumericEditField
        lambdamyEditFieldLabel         matlab.ui.control.Label
        lambdamyEditField              matlab.ui.control.NumericEditField
        DefaultsForgettingButton       matlab.ui.control.Button
    end

    properties (Access = public)
        alpha1 % Time constant of Laguerre model
        Nis % Order of the Laguerre model
        lambdamy % Model forgetting values and limits for the Laguerre-based approach
        lambdany
        lambdamL
        lambdanL
        lambdamu
        lambdanu
        etaL1
        etaY
        etaCond
        lambdaR
        etaChi
        salpha1 % Time constant of Laguerre model converted to characters
        sNis % Order of the Laguerre model  converted to characters
        slambdamy % Model forgetting values and limits for the Laguerre-based approach converted to characters
        slambdany
        slambdamL
        slambdanL
        slambdamu
        slambdanu
        setaL1
        setaY
        setaCond
        slambdaR
        setaChi
        rawDataS % Structure with raw numeric input data from the MAT-file
        rawData % Raw numeric input data from the MAT-file converted to double
        rawTextS % Structure with cells containing text from the MAT-file (Variables names, descriptions, tags, time samples)
        rawText % Cells containing text from the MAT-file
        dataTag % Tags of the variables from the MAT-file (row matrix)
        dataDescription % Descriptions of the variables from the MAT-file (row matrix)
        dataName % Names of the variables from the MAT-file (row matrix)
        dataTime % Time samples of the variable measurements from the MAT-file (row matrix)
        rawTimeLength % Length of the raw time samples array
        timeLim % Time interval of interest specified by the user (two values [tmin tmax])
        timeMin
        timeMax
        var1
        u % Input variable defined by the user
        y % Output variable defined by the user
        r % Setpoint variable defined by the user
        InValue
        OutValue
        SetpValue
        parameters % Cell array of the character names of all parameters (corresponds to the input of the performDQmv 'specifications')
        parvalues % Cell array of the character values of all parameters (corresponds to the input of the performDQmv 'values')
        dqF % Output of performDQmv that we plot as "partitions"
        isomodel % Second output of performDQmv
        InMin % Lower limit on input value (Input constraint)
        InMax % Upper limit on input value (Input constraint)
        SpMin % Lower limit on setpoint value (Setpoint constraint)
        SpMax % Upper limit on setpoint value (Setpoint constraint)
        InValMinMask % Mask for lower limit on input value
        InValMaxMask % Mask for upper limit on input value
        InValMask % Mask for upper and lower limits on input value combined
        SpValMinMask % Mask for lower limit on setpoint value
        SpValMaxMask % Mask for upper limit on setpoint value
        SpValMask % Mask for upper and lower limits on setpoint value combined
        ValMask % Mask for upper and lower limits on input and setpoint values combined
        u1 % TEMPORARY SOLUTION! input variable after applying ALL limits on value
        y1 % TEMPORARY SOLUTION! output variable after applying ALL limits on value
        r1 % TEMPORARY SOLUTION! setpoint variable after applying ALL limits on value

    
       end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.alpha1 = 0.8;% Set default values of the parameters
            app.Nis = 6;
            app.lambdamy = 0.99;
            app.lambdany = 0.99;
            app.lambdamL = 0.99;
            app.lambdanL = 0.99;
            app.lambdamu = 0.99;
            app.lambdanu = 0.99;
            app.etaL1 = 1e-7;
            app.etaY = 1e-10;
            app.etaCond = 1e6;
            app.lambdaR = 0.95;
            app.etaChi = 10^3;
            app.parameters = {'alpha1'; 'Nis'; 'lambdamy'; 'lambdany'; 'lambdamL'; 'lambdanL'; 'lambdamu'; 'lambdanu'; 'etaL1'; 'etaY'; 'etaCond'; 'lambdaR'; 'etaChi'}; % Cell array of the character names of all parameters
            app.InCritValPanel.Enable = 'off';
            app.AddInConstraintsPanel.Enable='off';
            app.LowerInLimValEditField.Enable='off';
            app.UpperInLimValEditField.Enable='off';
            app.UpperInLimVarEditField.Enable='off';
            app.LowerInLimVarEditField.Enable='off';
            app.ImplementConstraintsButton.Enable='off';
            app.InCritValEditField.Enable='off';
            app.InSaturatedloopCheckBox.Enable='off';
            app.InFreezeCheckBox.Enable='off';
            app.InAbruptChangeCheckBox.Enable='off';
            app.InmovvarkEditField.Enable='off';
            app.LowerSetpLimValEditField.Enable='off';
            app.UpperSetpLimValEditField.Enable='off';
            app.UpperSetpLimVarEditField.Enable='off';
            app.LowerSetpLimVarEditField.Enable='off';
            app.SetpmovvarkEditField.Enable='off';
            app.SetpCritValEditField.Enable='off';
            app.SelectValueLimits_4.Enable='off';
            app.SetpSaturatedloopCheckBox.Enable='off';
            app.SetpFreezeCheckBox.Enable='off';
            app.SetpAbruptChangeCheckBox.Enable='off';
            app.SelectValueLimits_5.Enable='off';
            app.ImplementConstraintsButton_2.Enable='off';
            app.SelectValueLimits_2.Enable='off';
             app.SelectValueLimits_3.Enable='off';
              app.SelectValueLimits_4.Enable='off';
               app.SelectValueLimits_5.Enable='off';
               app.LowerSetpLimValEditField.Value=0;
               app.UpperSetpLimValEditField.Value=0;

            
        end

        % Value changed function: alpha1EditField
        function alpha1EditFieldValueChanged(app, event)
            app.alpha1 = app.alpha1EditField.Value; % Assign new value to the parameter
        end

        % Value changed function: NisEditField
        function NisEditFieldValueChanged(app, event)
            app.Nis = app.NisEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdanyEditField
        function lambdanyEditFieldValueChanged(app, event)
            app.lambdany = app.lambdanyEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdamLEditField
        function lambdamLEditFieldValueChanged(app, event)
            app.lambdamL = app.lambdamLEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdanLEditField
        function lambdanLEditFieldValueChanged(app, event)
            app.lambdanL = app.lambdanLEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdamuEditField
        function lambdamuEditFieldValueChanged(app, event)
            app.lambdamu = app.lambdamuEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdanuEditField
        function lambdanuEditFieldValueChanged(app, event)
            app.lambdanu = app.lambdanuEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: etaL1EditField
        function etaL1EditFieldValueChanged(app, event)
            app.etaL1 = app.etaL1EditField.Value; % Assign new value to the parameter
        end

        % Value changed function: etaYEditField
        function etaYEditFieldValueChanged(app, event)
            app.etaY = app.etaYEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: etaCondEditField
        function etaCondEditFieldValueChanged(app, event)
            app.etaCond = app.etaCondEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdaREditField
        function lambdaREditFieldValueChanged(app, event)
            app.lambdaR = app.lambdaREditField.Value; % Assign new value to the parameter
        end

        % Value changed function: etaChiEditField
        function etaChiEditFieldValueChanged(app, event)
            app.etaChi = app.etaChiEditField.Value; % Assign new value to the parameter
        end

        % Value changed function: lambdamyEditField
        function lambdamyEditFieldValueChanged(app, event)
            app.lambdamy = app.lambdamyEditField.Value; % Assign new value to the parameter
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename, pathname, filterindex] = uigetfile( {'*.csv; *.xls; *.xlsx,*.xlsm', 'Excel Files (*.csv, *.xls, *.xlsx,.xlsm)'; '*.mat', 'MAT-files (*.mat)'}); % Load file
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------%
            try
            %------------------------------------New_Code_end_2022-02-04------------------%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                figwait1 = uifigure;%Create Empty Dialog field
                figwait1.WindowStyle='modal';
                dlgwait1 = uiprogressdlg (figwait1,'Title', 'Loading dataset',...
                'Indeterminate', 'on');%Define the Progress Dialog

                         
                if ischar (filename) && filterindex == 2 % If MAT-file was selected
                
                   Raw_data_struct=load([pathname, filename],'-mat');%load Data from *.mat file
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------New_Code_begin_2022-02-04----------------%   
                
                    Raw_data_cell=struct2cell(Raw_data_struct);%convert the type struct to cells
                    Raw_data=Raw_data_cell{5,1};%Pass the variable 
            
                %------------------------------------New_Code_end_2022-02-04------------------%            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------Old_Code_begin_2022-02-04----------------%                
                %datainMat = whos ('-file', fullfile (pathname, filename));
                %if strcmp (datainMat(1).class, 'double') && strcmp (datainMat(2).class, 'cell') % Conditions for the required MAT-file structure
                %   app.rawDataS = load (fullfile (pathname, filename), datainMat(1).name); % Load raw variable values structure
                %    app.rawData = app.rawDataS.data; % Convert raw variable values to double
                %    app.rawTextS = load (fullfile (pathname, filename), datainMat(2).name); % Load raw text structure
                %   app.rawText = app.rawTextS.text; % Convert raw text to cells
                %   app.dataTag = app.rawText (1, 2 : end); % Create tags of the variables row matrix
                %    app.dataDescription = app.rawText (2, 2 : end); % Create descriptions of the variables row matrix
                %    app.dataName = app.rawText (3, 2 : end); % Create names of the variables row matrix
                %   app.dataTime = app.rawText (4 : end, 1)'; % Create time samples of the variable measurements row matrix
                %end 
                
                %------------------------------------Old_Code_end_2021-02-24----------------% 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if ischar (filename) && filterindex == 1 % If *.csv or *.xlsx was selected

                %------------------------------------Old_Code_begin_2022-02-04----------------% 
             
                % app.rawData = xlsread (fullfile (pathname, filename)); % Convert raw variable values to double
                %[~, app.dataTag, ~] = xlsread (fullfile (pathname, filename), '1:1'); % Create tags of the variables row matrix
                % [~, app.dataDescription, ~] = xlsread (fullfile (pathname, filename), '2:2'); % Create descriptions of the variables row matrix
                % [~, app.dataName, ~] = xlsread (fullfile (pathname, filename), '3:3'); % Create names of the variables row matrix
                %[~, app.dataTime, ~] = xlsread (fullfile (pathname, filename), 'A:A');  % Create time samples of the variable measurements row matrix  
                
                %------------------------------------Old_Code_end_2021-02-24------------------% 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------New_Code_begin_2022-02-04----------------%
                    opts = detectImportOptions([pathname,filename]);%Create import options based on file content
                    opts.VariableNamingRule='preserve';%The names should not change
                    opts.DataLines=[4 Inf];%take the data from row 4 until end
                    Raw_data=readtable([pathname,filename],opts);%reading the data and convert in table

                %------------------------------------New_Code_end_2022-02-04------------------%            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------New_Code_begin_2022-02-04----------------%    
               %Raw_data=rmmissing(Raw_data);%Removing all Cells, which involve NaT,NaN ...
                app.dataTime=Raw_data(:,1);%Create a variable from Time
                app.dataDescription=Raw_data.Properties.Description;%Create descriptions of the variable
                [high,~]=size(Raw_data);%take the high from the table
                row=(1:high)';%define the row length
                app.dataTag=["Tagname" Raw_data.Properties.VariableNames(2:end)];%Create tags of the variables Columns
                app.rawData=table2array([table(row*0) Raw_data(:,2:end)]);%Convert raw Data in double with a Default Column as Tagname
                app.dataTime=Raw_data(:,1);%creates a variable with the length of the first column (time) of the table rawdata

                %------------------------------------New_Code_end_2022-02-04------------------%            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                plot (app.OriginalUIAxes, app.rawData); % Plot raw data (all variables)

                TimeLabelText = 'Select time interval in the range [1 %d]'; % Modify time edit field label according to the size of the time vector
                EditFieldText = '[1 %d]'; 
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------Old_Code_begin_2022-02-04----------------%
                %             app.rawTimeLength = length (app.dataTime); % Calculate XMax
                %------------------------------------Old_Code_end_2022-02-04------------------% 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------New_Code_begin_2022-02-04----------------%
                            app.rawTimeLength = height (app.dataTime); % Calculate XMax
                %------------------------------------New_Code_end_2022-02-04------------------%            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.SelecttimeintervalLabel.Text = sprintf (TimeLabelText, app.rawTimeLength);
                app.SelectTimeEditField.Value = sprintf (EditFieldText, app.rawTimeLength); % Edit field is prefilled with default value
                app.SelectTimeEditField.Enable = 'on';
      
                app.SelectInDropDown.Items =app.dataTag; % Update and enable input  
                app.SelectInDropDown.ItemsData = (1 : length (app.dataTag));
                app.SelectInDropDown.Enable = 'on';
    
                app.SelectOutDropDown.Items = app.dataTag; % Update and enable output
                app.SelectOutDropDown.ItemsData = (1 : length (app.dataTag));
                app.SelectOutDropDown.Enable = 'on';
                
                app.SelectSetpDropDown.Items = app.dataTag; % Update and enable setpoint
                app.SelectSetpDropDown.ItemsData = (1 : length (app.dataTag));
                app.NoSetpButton.Enable = 'on';
                app.YesSetpButton.Enable = 'on';  
                app.OrigPlotButton.Enable='on'; % Enable parameters tab
              
               
                app.alpha1EditField.Editable = 'on';
                app.NisEditField.Editable = 'on';
                app.DefaultsLaguerreButton.Enable = 'on';
                app.lambdanyEditField.Editable = 'on';
                app.lambdamLEditField.Editable = 'on';
                app.lambdanLEditField.Editable = 'on';
                app.lambdamuEditField.Editable = 'on';
                app.lambdanuEditField.Editable = 'on';
                app.etaL1EditField.Editable = 'on';
                app.etaYEditField.Editable = 'on';
                app.etaCondEditField.Editable = 'on';
                app.lambdaREditField.Editable = 'on';
                app.etaChiEditField.Editable = 'on';
                app.lambdamyEditField.Editable = 'on';
                app.DefaultsForgettingButton.Enable = 'on';
                            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %------------------------------------New_Code_begin_2022-02-04----------------%         
                %interval is now an option to change
                app.timeLim = eval (app.SelectTimeEditField.Value);
                if app.timeLim(1) > 0 && app.timeLim(2) > app.timeLim(1) && app.timeLim(2) <= app.rawTimeLength % Conditions for min and max values
                    app.timeMin = app.timeLim(1);%max Intevall value
                    app.timeMax = app.timeLim(2);%min Intevall value
                end
                %------------------------------------New_Code_end_2022-02-04------------------%            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                pause(.1)
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------%
            catch ME
                close (dlgwait1)
                delete (figwait1)
                str={'Load process did not complete'};
                uialert(uifigure, str,'Warning','Icon','warning');
            end
            %------------------------------------New_Code_end_2022-02-04------------------%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            close (dlgwait1)
            delete (figwait1)
        end

        % Selection changed function: SetpButtonGroup
        function SetpButtonGroupSelectionChanged(app, event)
            
            if app.SetpButtonGroup.SelectedObject == app.YesSetpButton
                app.SelectSetpDropDown.Enable = 'on';
                
                app.ImplementConstraintsButton_2.Enable='on';
                app.LowerSetpLimValEditField.Enable = 'off'; 
                app.UpperSetpLimValEditField.Enable = 'off';
                app.SetpCritValEditField.Enable = 'off';
                app.LowerSetpLimVarEditField.Enable = 'off';
                app.UpperSetpLimVarEditField.Enable = 'off';
                app.SetpmovvarkEditField.Enable = 'off';
                app.SetpAbruptChangeCheckBox.Enable = 'off';
                app.SetpFreezeCheckBox.Enable = 'off';
                app.SetpSaturatedloopCheckBox.Enable = 'off';
            else
                app.SelectSetpDropDown.Enable = 'off';
                app.SelectSetpDropDown.Value = app.SelectOutDropDown.Value;
                app.SelectValueLimits_2.Enable='off';
                app.SelectValueLimits_3.Enable='off';
                %for the future
                %app.SelectValueLimits_4.Enable='off';
                %app.SelectValueLimits_5.Enable='off';
                
                
                %app.LowerSetpLimValEditField.Value = 0;
                %app.UpperSetpLimValEditField.Value = 0;
                %app.LowerSetpLimValEditField.Enable = 'off'; % Disable setpoint constraints
                %app.UpperSetpLimValEditField.Enable = 'off';
                %app.SetpCritValEditField.Enable = 'off';
                %app.LowerSetpLimVarEditField.Enable = 'off';
                %app.UpperSetpLimVarEditField.Enable = 'off';
                %app.SetpmovvarkEditField.Enable = 'off';
                %app.SetpAbruptChangeCheckBox.Enable = 'off';
                %app.SetpFreezeCheckBox.Enable = 'off';
                %app.SetpSaturatedloopCheckBox.Enable = 'off';
            end
        end

        % Value changed function: SelectOutDropDown
        function SelectOutDropDownValueChanged(app, event)
            app.OutValue = app.SelectOutDropDown.Value;
            if app.SetpButtonGroup.SelectedObject == app.NoSetpButton
               app.SelectSetpDropDown.Value = app.SelectOutDropDown.Value;
              
            end
        end

        % Callback function: DefaultsLaguerreButton, InPanel
        function DefaultsLaguerreButtonPushed(app, event)
            app.alpha1EditField.Value = 0.8; % Restore default values of the parameters group
            app.alpha1 = 0.8;
            app.NisEditField.Value = 6;
            app.Nis = 6;
        end

        % Button pushed function: DefaultsForgettingButton
        function DefaultsForgettingButtonPushed(app, event)
            app.lambdanyEditField.Value = 0.99; % Restore default values of the parameters group
            app.lambdany = 0.99;
            app.lambdamLEditField.Value = 0.99;
            app.lambdamL = 0.99;
            app.lambdanLEditField.Value = 0.99;
            app.lambdanL = 0.99;
            app.lambdamuEditField.Value = 0.99;
            app.lambdamu = 0.99;
            app.lambdanuEditField.Value = 0.99;
            app.lambdanu = 0.99;
            app.etaL1EditField.Value = 1e-7;
            app.etaL1 = 1e-7;
            app.etaYEditField.Value = 1e-10;
            app.etaY = 1e-10;
            app.etaCondEditField.Value = 1e6;
            app.etaCond = 1e6;
            app.lambdaREditField.Value = 0.95;
            app.lambdaR = 0.95;
            app.etaChiEditField.Value = 10^3;
            app.etaChi = 10^3;
            app.lambdamyEditField.Value = 0.99;
            app.lambdamy = 0.99;
        end

        % Value changed function: SelectTimeEditField
        function SelectTimeEditFieldValueChanged(app, event)
            app.timeLim = eval (app.SelectTimeEditField.Value);
            if app.timeLim(1) > 0 && app.timeLim(2) > app.timeLim(1) && app.timeLim(2) <= app.rawTimeLength % Conditions for min and max values
                app.timeMin = app.timeLim(1);
                app.timeMax = app.timeLim(2);

            else
                error1 = errordlg ('Use format [min, max] where min => 1 && max > min && max <= app.rawTimeLength','Time Interval Error'); % TODO: Change error message
            end
        end

        % Value changed function: SelectInDropDown
        function SelectInDropDownValueChanged(app, event)
            app.InValue = app.SelectInDropDown.Value;
        end

        % Button pushed function: OrigPlotButton
        function OrigPlotButtonPushed(app, event)

            app.u = app.rawData (app.timeMin:app.timeMax, app.InValue);

          
            app.y = app.rawData (app.timeMin:app.timeMax, app.OutValue);
            app.r = app.rawData (app.timeMin:app.timeMax, app.SetpValue);
                        
          
            if app.SetpButtonGroup.SelectedObject == app.YesSetpButton
                app.r = app.rawData (app.timeMin:app.timeMax, app.SetpValue);
                 
                app.var1 = [app.r, app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'setpoint', 'output', 'input');

            elseif app.SetpButtonGroup.SelectedObject == app.NoSetpButton
                
                app.var1 = [app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'output', 'input');
            end
           
         app.CalculatePartitionsButton.Enable = 'on';
         app.ImplementConstraintsButton.Enable='on';
        
        end

        % Button pushed function: CalculatePartitionsButton
        function CalculatePartitionsButtonPushed(app, event)
          
            
            figwait2 = uifigure;
            figwait2.WindowStyle='modal';
            
            dlgwait2 = uiprogressdlg (figwait2,'Title','Calculating partitions','Cancelable','on');%,...
        %        'Indeterminate','on');
            drawnow
            app.salpha1 = sprintf ('%f', app.alpha1); % Convert parameter values to the characters
            app.sNis = sprintf ('%f', app.Nis);
            app.slambdamy = sprintf ('%f', app.lambdamy);
            app.slambdany = sprintf ('%f', app.lambdany);
            app.slambdamL = sprintf ('%f', app.lambdamL);
            app.slambdanL = sprintf ('%f', app.lambdanL);
            app.slambdamu = sprintf ('%f', app.lambdamu);
            app.slambdanu = sprintf ('%f', app.lambdanu);
            app.setaL1 = sprintf ('%u', app.etaL1);
            app.setaY = sprintf ('%u', app.etaY);
            app.setaCond = sprintf ('%u', app.etaCond);
            app.slambdaR = sprintf ('%f', app.lambdaR);
            app.setaChi = sprintf ('%u', app.etaChi);
            app.parvalues = {app.salpha1; app.sNis; app.slambdamy; app.slambdany; app.slambdamL; app.slambdanL; app.slambdamu; app.slambdanu; app.setaL1; app.setaY; app.setaCond; app.slambdaR; app.setaChi}; % Cell array of the character values of all parameters
            if app.SetpButtonGroup.SelectedObject == app.NoSetpButton
                app.r = app.y;
            end
            
            [app.dqF, app.isomodel] = performDQmv (app.r, app.y, app.u, dlgwait2, app.parameters, app.parvalues); % Call performDQmv code
     
                 
            plot (app.PartitionsUIAxes, app.dqF,'o');
            subtitle(app.PartitionsUIAxes,"A value of zero implies bad data. Each plateau represents a good data region.");
            
            
            app.SavePartitionsButton.Enable = 'on';
            pause (.1)
            close (dlgwait2)
            delete(dlgwait2)
            delete (figwait2)
        end

        % Button pushed function: SavePartitionsButton
        function SavePartitionsButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------% 
            try
            %------------------------------------New_Code_End_2022-02-04----------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            dqF = app.dqF;
            isomodel = app.isomodel;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------% 
            try
                Select=app.SelectSetpDropDown(app.SelectSetpDropDown.Value); %if nothing has been changed in tab 3 of the parameter settings, say that is empty  
            catch ME
                Select="Empty";
            end
            
             Parameter_Setting=struct('TimeLim',app.timeLim,'alpha1',app.alpha1,'Nis',app.Nis,'lambdamy',app.lambdamy,...
                'lambdany',app.lambdany,'lambdamL',app.lambdamL,'lambdanu',app.lambdanu,'etaL1',app.etaL1,'etaY',app.etaY,'etaCond',app.etaCond,'lambdaR',app.lambdaR,...
                'etaChi',app.etaChi,'LowerInLim',app.InMin,'UpperInLim',app.InMax,'Select_Value',Select);%Parameter_Settings_Save

            input=char(app.SelectInDropDown.Items(app.SelectInDropDown.Value));%take the input-name which was selected from dropdown
            output=char(app.SelectOutDropDown.Items(app.SelectOutDropDown.Value));
            %------------------------------------New_Code_End_2022-02-04----------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            DateString = datestr (now,'yyyy-mmmm-dd HH-MM-SS');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------Old_Code_begin_2022-02-04----------------% 
            %dqOut = ['dqOut ', DateString];
            %------------------------------------Old_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------% 
            dqOut = ['dqOut_',input,'_',output,'_',DateString];
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [file, path, index] = uiputfile ({'*.mat', 'MAT-files (*.mat)'; '*.csv', 'Text (*.csv)'; ...
                                '*.xlsx; *.xls', 'Spreadsheet (*.xlsx, *.xls)'}, ...
                                'Select File to Write', dqOut);
            filename = fullfile (path, file);
            C = {'dqF', 'isomodel'};

            if index == 1
                save (filename,"Parameter_Setting",'dqF', 'isomodel');   % Save to MAT-file

            elseif index == 2
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------%  
                    writetable(table(dqF,isomodel,'VariableNames',C),filename);
                    writetable(struct2table(Parameter_Setting,"AsArray",true),[path,'Parameter_Settings_',dqOut,'.csv'],"FileType","text");
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------Old_Code_begin_2022-02-04----------------% 
                   %xlswrite (filename, C);
                   %xlswrite (filename, [dqF isomodel], 1, 'A2'); % Save to CSV
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            elseif index == 3
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------% 
                   writetable(table(dqF,isomodel,'VariableNames',C),filename,'Sheet',1);%save as XLXS in Sheet(1)
                   writetable(struct2table(Parameter_Setting,"AsArray",true),filename,'Sheet',2);%Save as XLSX in Sheet(2)   
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------Old_Code_begin_2022-02-04----------------%
                   %xlswrite (filename, C);
                   %xlswrite (filename, [dqF isomodel], 1, 'A2'); % Save to XLS
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            end
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------New_Code_begin_2022-02-04----------------% 
                figwait3 = uifigure;
                figwait3.WindowStyle='modal';
                str={'The file was saved.'};
                uialert(figwait3, str,'Saved','Icon','success');
               
            catch ME
                str={'The file was not saved.'};
                uialert(uifigure, str,'Warning','Icon','warning');
            end
            %------------------------------------New_Code_End_2022-02-04------------------% 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Value changed function: SelectSetpDropDown
        function SelectSetpDropDownValueChanged(app, event)
            app.SetpValue = app.SelectSetpDropDown.Value;
            if app.SetpValue>1
                app.SelectValueLimits_2.Enable='on';
                app.SelectValueLimits_3.Enable='on';
                %for the future
                %app.SelectValueLimits_4.Enable='on';
                %app.SelectValueLimits_5.Enable='on';
                 app.r = app.rawData (app.timeMin:app.timeMax, app.SetpValue);
     
                
                app.var1 = [app.r, app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'setpoint', 'output', 'input');
            else
                %Do nothing since the the user did not select any viable
                %option.
                app.r = app.rawData (app.timeMin:app.timeMax, app.SetpValue);
            end
        end

        % Value changed function: LowerInLimValEditField
        function LowerInLimValEditFieldValueChanged(app, event)
            app.InMin = app.LowerInLimValEditField.Value;
        end

        % Value changed function: UpperInLimValEditField
        function UpperInLimValEditFieldValueChanged(app, event)
            app.InMax = app.UpperInLimValEditField.Value;
        end

        % Value changed function: LowerSetpLimValEditField
        function LowerSetpLimValEditFieldValueChanged(app, event)
            app.SpMin = app.LowerSetpLimValEditField.Value;
        end

        % Value changed function: UpperSetpLimValEditField
        function UpperSetpLimValEditFieldValueChanged(app, event)
            app.SpMax = app.UpperSetpLimValEditField.Value;
        end

        % Size changed function: OutPanel
        function OutPanelSizeChanged(app, event)
            position = app.OutPanel.Position;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Value changing function: SelectTimeEditField
        function SelectTimeEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button down function: OriginalUIAxes
        function OriginalUIAxesButtonDown(app, event)
            
        end

        % Drop down opening function: SelectSetpDropDown
        function SelectSetpDropDownOpening(app, event)
            
        end

        % Drop down opening function: SelectOutDropDown
        function SelectOutDropDownOpening(app, event)
            
        end

        % Callback function
        function BreakRunningPartitionButtonPushed(app, event)
            quit()
            close figure
            DQGUI
            
        end

        % Button down function: PartitionsUIAxes
        function PartitionsUIAxesButtonDown(app, event)
           
        end

        % Button pushed function: ImplementConstraintsButton
        function ImplementConstraintsButtonPushed(app, event)
            %Essentially what needs to be done is simply:
            %a) find the variable.
            %b) implement the changes.
            %c) replot the graph.
            %Added Yuri A.W. Shardt 20220412 n.s.
            input=app.u;
            output=app.y;
            %Bounds
            if app.SelectValueLimits.Value
            
            
            loweru=app.LowerInLimValEditField.Value;
            upperu=app.UpperInLimValEditField.Value;
            
            input(input>upperu)=NaN;
            input(input<loweru)=NaN;
            
            output(input>upperu)=NaN;
            output(input<loweru)=NaN;
            end
            if app.SelectVarLimits.Value
            %Variance bounds
            window=app.InmovvarkEditField.Value;
            
            varLower=app.LowerInLimVarEditField.Value;
            varUpper=app.UpperInLimVarEditField.Value;
            
            for i=1:length(input)-window
                variance=var(input(i:i+window-1));
                if variance>varUpper || variance<varLower
                    %set whole region to NaN?
                    input(i:i+window-1)=NaN;
                    output(i:i+window-1)=NaN;
                    i=i+window;
                end
            
            end
            end
            app.u=input;
            app.y=output;
            
            if app.SetpButtonGroup.SelectedObject == app.YesSetpButton
                
                app.var1 = [app.r, app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'setpoint', 'output', 'input');

            elseif app.SetpButtonGroup.SelectedObject == app.NoSetpButton
               
                app.var1 = [app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'output', 'input');
            end
            app.TabGroup.SelectedTab = app.DQATab;
            
        end

        % Value changed function: SelectValueLimits
        function SelectValueLimitsValueChanged(app, event)
            value = app.SelectValueLimits.Value;
            
            if value
                 app.LowerInLimValEditField.Enable='on';
            app.UpperInLimValEditField.Enable='on';
            else
                  app.LowerInLimValEditField.Enable='off';
            app.UpperInLimValEditField.Enable='off';     
            end
            
        end

        % Value changed function: SelectVarLimits
        function SelectVarLimitsValueChanged(app, event)
            value = app.SelectVarLimits.Value;
            if value
                app.UpperInLimVarEditField.Enable='on';
            app.LowerInLimVarEditField.Enable='on';
            app.InmovvarkEditField.Enable='on';
            else
                app.UpperInLimVarEditField.Enable='off';
            app.LowerInLimVarEditField.Enable='off';
            app.InmovvarkEditField.Enable='off';
            end
        end

        % Value changed function: SelectCritValues
        function SelectCritValuesValueChanged(app, event)
            value = app.SelectCritValues.Value;
            if value
                app.InCritValEditField.Enable='on';
            else
                app.InCritValEditField.Enable='off';
            end
        end

        % Value changed function: SelectAddConstraints
        function SelectAddConstraintsValueChanged(app, event)
            value = app.SelectAddConstraints.Value;
            if value
                app.InSaturatedloopCheckBox.Enable='on';
            app.InFreezeCheckBox.Enable='on';
            app.InAbruptChangeCheckBox.Enable='on';
            else
                app.InSaturatedloopCheckBox.Enable='off';
            app.InFreezeCheckBox.Enable='off';
            app.InAbruptChangeCheckBox.Enable='off';
            end
        end

        % Value changed function: SelectValueLimits_2
        function SelectValueLimits_2ValueChanged(app, event)
            value = app.SelectValueLimits_2.Value;
            if value
                 app.LowerSetpLimValEditField.Enable='on';
            app.UpperSetpLimValEditField.Enable='on';
            else
                  app.LowerSetpLimValEditField.Enable='off';
            app.UpperSetpLimValEditField.Enable='off';     
            end
        end

        % Value changed function: SelectValueLimits_3
        function SelectValueLimits_3ValueChanged(app, event)
            value = app.SelectValueLimits_3.Value;
            
            if value
                app.UpperSetpLimVarEditField.Enable='on';
            app.LowerSetpLimVarEditField.Enable='on';
            app.SetpmovvarkEditField.Enable='on';
            else
                app.UpperSetpLimVarEditField.Enable='off';
            app.LowerSetpLimVarEditField.Enable='off';
            app.SetpmovvarkEditField.Enable='off';
            end
        end

        % Value changed function: SelectValueLimits_4
        function SelectValueLimits_4ValueChanged(app, event)
            value = app.SelectValueLimits_4.Value;
            if value
                app.SetpCritValEditField.Enable='on';
            else
                app.SetpCritValEditField.Enable='off';                
            end
        end

        % Value changed function: SelectValueLimits_5
        function SelectValueLimits_5ValueChanged(app, event)
            value = app.SelectValueLimits_5.Value;
             if value
                app.SetpSaturatedloopCheckBox.Enable='on';
            app.SetpFreezeCheckBox.Enable='on';
            app.SetpAbruptChangeCheckBox.Enable='on';
            else
                app.SetpSaturatedloopCheckBox.Enable='off';
            app.SetpFreezeCheckBox.Enable='off';
            app.SetpAbruptChangeCheckBox.Enable='off';        
            end
        end

        % Button pushed function: ImplementConstraintsButton_2
        function ImplementConstraintsButton_2Pushed(app, event)
            %Essentially what needs to be done is simply:
            %a) find the variable.
            %b) implement the changes.
            %c) replot the graph.
            %Added Yuri A.W. Shardt 20220412 n.s.
            reference=app.r;
            input=app.u;
            output=app.y;
         
            %Bounds
            if app.SelectValueLimits_2.Value
            
            
            loweru=app.LowerSetpLimValEditField.Value;
            upperu=app.UpperSetpLimValEditField.Value;
            
            reference(reference>upperu)=NaN;
            reference(reference<loweru)=NaN;
            
            output(reference>upperu)=NaN;
            output(reference<loweru)=NaN;
            
            input(reference>upperu)=NaN;
            input(reference<loweru)=NaN;
            end
            if app.SelectVarLimits.Value
            %Variance bounds
            window=app.SetpmovvarkEditField.Value;
            
            varLower=app.LowerSetpLimVarEditField.Value;
            varUpper=app.UpperSetpLimVarEditField.Value;
            
            for i=1:length(input)-window
                variance=var(reference(i:i+window-1));
                if variance>varUpper || variance<varLower
                    %set whole region to NaN?
                    reference(i:i+window-1)=NaN;
                    input(i:i+window-1)=NaN;
                    output(i:i+window-1)=NaN;
                    i=i+window;
                end
            
            end
            end
            app.u=input;
            app.y=output;
            app.r=reference;
            
            if app.SetpButtonGroup.SelectedObject == app.YesSetpButton
                %app.r = app.rawData (app.timeMin:app.timeMax, app.SetpValue);
     
                app.LowerSetpLimValEditField.Value = min (app.r); % Edit field is prefilled with min value
                app.UpperSetpLimValEditField.Value = max (app.r); % Edit field is prefilled with max value
                app.LowerSetpLimValEditField.Enable = 'on'; % Enable setpoint constraints
                app.UpperSetpLimValEditField.Enable = 'on';
                app.SetpCritValEditField.Enable = 'on';
                app.LowerSetpLimVarEditField.Enable = 'on';
                app.UpperSetpLimVarEditField.Enable = 'on';
                app.SetpmovvarkEditField.Enable = 'on';
                app.SetpAbruptChangeCheckBox.Enable = 'on';
                app.SetpFreezeCheckBox.Enable = 'on';
                app.SetpSaturatedloopCheckBox.Enable = 'on';
                
                app.var1 = [app.r, app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'setpoint', 'output', 'input');

            elseif app.SetpButtonGroup.SelectedObject == app.NoSetpButton
                %app.ValMask = (app.InValMask ~= 0);
                %app.u = app.u (app.ValMask);
                %app.y = app.y (app.ValMask);
                app.var1 = [app.y, app.u];
                plot (app.OriginalUIAxes, app.var1);
                legend (app.OriginalUIAxes, 'output', 'input');
            end
            app.TabGroup.SelectedTab = app.DQATab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataQualityAssessmentApplicationUIFigure = uifigure('Visible', 'off');
            app.DataQualityAssessmentApplicationUIFigure.Name = 'Data Quality Assessment Application';
            app.TabGroup = uitabgroup(app.DataQualityAssessmentApplicationUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DQATab = uitab(app.TabGroup);
            app.DQATab.Title = 'DQA';
            app.LoadDataButton = uibutton(app.DQATab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data';
            app.OrigPlotButton = uibutton(app.DQATab, 'push');
            app.OrigPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OrigPlotButtonPushed, true);
            app.OrigPlotButton.Enable = 'off';
            app.OrigPlotButton.Tooltip = {'Select input, output, and time interval to plot. Overrides any settings selected in Input Settings and Setpoint Settings tabs.'};
            app.OrigPlotButton.Text = 'Save settings and plot';
            app.CalculatePartitionsButton = uibutton(app.DQATab, 'push');
            app.CalculatePartitionsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePartitionsButtonPushed, true);
            app.CalculatePartitionsButton.Enable = 'off';
            app.CalculatePartitionsButton.Tooltip = {'Select input, output, time interval and plot the original data to calculate the partitions.'};
            app.CalculatePartitionsButton.Text = 'Calculate partitions';
            app.SavePartitionsButton = uibutton(app.DQATab, 'push');
            app.SavePartitionsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePartitionsButtonPushed, true);
            app.SavePartitionsButton.Enable = 'off';
            app.SavePartitionsButton.Tooltip = {'Calculate partitions to save the result.'};
            app.SavePartitionsButton.Text = 'Save partitions';
            app.SelecttimeintervalLabel = uilabel(app.DQATab);
            app.SelecttimeintervalLabel.Text = 'Select time interval';
            app.SelectTimeEditField = uieditfield(app.DQATab, 'text');
            app.SelectTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SelectTimeEditFieldValueChanged, true);
            app.SelectTimeEditField.ValueChangingFcn = createCallbackFcn(app, @SelectTimeEditFieldValueChanging, true);
            app.SelectTimeEditField.Enable = 'off';
            app.SelectTimeEditField.Tooltip = {'Load data set to select time interval.'};
            app.InPanel = uipanel(app.DQATab);
            app.InPanel.Tooltip = {'Load data set to select input.'};
            app.InPanel.Title = 'Input u';
            app.InPanel.SizeChangedFcn = createCallbackFcn(app, @DefaultsLaguerreButtonPushed, true);
            app.SelectInDropDownLabel = uilabel(app.InPanel);
            app.SelectInDropDownLabel.Text = 'Select variable';
            app.SelectInDropDown = uidropdown(app.InPanel);
            app.SelectInDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectInDropDownValueChanged, true);
            app.SelectInDropDown.Enable = 'off';
            app.SelectInDropDown.Tooltip = {'Load data set to select input.'};
            app.OutPanel = uipanel(app.DQATab);
            app.OutPanel.Tooltip = {'Load data set to select output.'};
            app.OutPanel.Title = 'Output y';
            app.OutPanel.SizeChangedFcn = createCallbackFcn(app, @OutPanelSizeChanged, true);
            app.SelectOutDropDownLabel = uilabel(app.OutPanel);
            app.SelectOutDropDownLabel.Text = 'Select variable';
            app.SelectOutDropDown = uidropdown(app.OutPanel);
            app.SelectOutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectOutDropDownOpening, true);
            app.SelectOutDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectOutDropDownValueChanged, true);
            app.SelectOutDropDown.Enable = 'off';
            app.SelectOutDropDown.Tooltip = {'Load data set to select output.'};
            app.OriginalUIAxes = uiaxes(app.DQATab);
            title(app.OriginalUIAxes, 'Original Data')
            app.OriginalUIAxes.ButtonDownFcn = createCallbackFcn(app, @OriginalUIAxesButtonDown, true);
            app.PartitionsUIAxes = uiaxes(app.DQATab);
            title(app.PartitionsUIAxes, 'Partitions')
            app.PartitionsUIAxes.ButtonDownFcn = createCallbackFcn(app, @PartitionsUIAxesButtonDown, true);
            app.InSettingsTab = uitab(app.TabGroup);
            app.InSettingsTab.Title = 'Input Settings';
            app.InConstraintsPanel = uipanel(app.InSettingsTab);
            app.InConstraintsPanel.Tooltip = {'Select input to change settings.'; 'This panel is currently under development.'};
            app.InConstraintsPanel.Title = 'Input constraints';
            app.InLimValPanel = uipanel(app.InConstraintsPanel);
            app.InLimValPanel.Tooltip = {'Select input, output, time interval and plot the original data to change settings.'; 'This panel is currently under development.'};
            app.InLimValPanel.Title = 'Limits on value';
            app.LowerInLimValEditFieldLabel = uilabel(app.InLimValPanel);
            app.LowerInLimValEditFieldLabel.Text = 'Lower';
            app.LowerInLimValEditField = uieditfield(app.InLimValPanel, 'numeric');
            app.LowerInLimValEditField.ValueChangedFcn = createCallbackFcn(app, @LowerInLimValEditFieldValueChanged, true);
            app.LowerInLimValEditField.Enable = 'off';
            app.LowerInLimValEditField.Tooltip = {'Select input, output, time interval and plot the original data to change settings.'};
            app.UpperInLimValEditFieldLabel = uilabel(app.InLimValPanel);
            app.UpperInLimValEditFieldLabel.Text = 'Upper';
            app.UpperInLimValEditField = uieditfield(app.InLimValPanel, 'numeric');
            app.UpperInLimValEditField.ValueChangedFcn = createCallbackFcn(app, @UpperInLimValEditFieldValueChanged, true);
            app.UpperInLimValEditField.Enable = 'off';
            app.UpperInLimValEditField.Tooltip = {'Select input, output, time interval and plot the original data to change settings.'};
            app.SelectValueLimits = uicheckbox(app.InLimValPanel);
            app.SelectValueLimits.ValueChangedFcn = createCallbackFcn(app, @SelectValueLimitsValueChanged, true);
            app.SelectValueLimits.Text = 'Click to Select';
            app.InCritValPanel = uipanel(app.InConstraintsPanel);
            app.InCritValPanel.Tooltip = {'Select input to change settings.'; 'This panel is currently under development.'};
            app.InCritValPanel.Title = 'Critical values';
            app.InCritValEditField = uieditfield(app.InCritValPanel, 'numeric');
            app.InCritValEditField.Enable = 'off';
            app.InCritValEditField.Tooltip = {'Select input to change settings.'; 'This panel is currently under development.'};
            app.SelectCritValues = uicheckbox(app.InCritValPanel);
            app.SelectCritValues.ValueChangedFcn = createCallbackFcn(app, @SelectCritValuesValueChanged, true);
            app.SelectCritValues.Text = 'Click to Select';
            app.InLimVarPanel = uipanel(app.InConstraintsPanel);
            app.InLimVarPanel.Tooltip = {'Select input to change settings.'; 'This panel is currently under development.'};
            app.InLimVarPanel.Title = 'Limits on variance';
            app.LowerInLimVarEditFieldLabel = uilabel(app.InLimVarPanel);
            app.LowerInLimVarEditFieldLabel.Text = 'Lower';
            app.LowerInLimVarEditField = uieditfield(app.InLimVarPanel, 'numeric');
            app.LowerInLimVarEditField.Enable = 'off';
            app.LowerInLimVarEditField.Tooltip = {'Select input to change settings.'};
            app.UpperInLimVarEditFieldLabel = uilabel(app.InLimVarPanel);
            app.UpperInLimVarEditFieldLabel.Text = 'Upper';
            app.UpperInLimVarEditField = uieditfield(app.InLimVarPanel, 'numeric');
            app.UpperInLimVarEditField.Enable = 'off';
            app.UpperInLimVarEditField.Tooltip = {'Select input to change settings.'};
            app.InmovvarkEditFieldLabel = uilabel(app.InLimVarPanel);
            app.InmovvarkEditFieldLabel.Text = 'Length of window';
            app.InmovvarkEditField = uieditfield(app.InLimVarPanel, 'numeric');
            app.InmovvarkEditField.Enable = 'off';
            app.InmovvarkEditField.Tooltip = {'Select input to change settings.'};
            app.SelectVarLimits = uicheckbox(app.InLimVarPanel);
            app.SelectVarLimits.ValueChangedFcn = createCallbackFcn(app, @SelectVarLimitsValueChanged, true);
            app.SelectVarLimits.Text = 'Click to Select';
            app.AddInConstraintsPanel = uipanel(app.InConstraintsPanel);
            app.AddInConstraintsPanel.Tooltip = {'Select input to change settings.'; 'This panel is currently under development.'};
            app.AddInConstraintsPanel.Title = 'Additional constraints';
            app.InAbruptChangeCheckBox = uicheckbox(app.AddInConstraintsPanel);
            app.InAbruptChangeCheckBox.Enable = 'off';
            app.InAbruptChangeCheckBox.Text = 'Abrupt change';
            app.InFreezeCheckBox = uicheckbox(app.AddInConstraintsPanel);
            app.InFreezeCheckBox.Enable = 'off';
            app.InFreezeCheckBox.Text = 'Freeze';
            app.InSaturatedloopCheckBox = uicheckbox(app.AddInConstraintsPanel);
            app.InSaturatedloopCheckBox.Enable = 'off';
            app.InSaturatedloopCheckBox.Text = 'Saturated loop';
            app.SelectAddConstraints = uicheckbox(app.AddInConstraintsPanel);
            app.SelectAddConstraints.ValueChangedFcn = createCallbackFcn(app, @SelectAddConstraintsValueChanged, true);
            app.SelectAddConstraints.Text = 'Click to Select';
            app.ImplementConstraintsButton = uibutton(app.InConstraintsPanel, 'push');
            app.ImplementConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ImplementConstraintsButtonPushed, true);
            app.ImplementConstraintsButton.Text = 'Implement Constraints';
            app.SetpSettingsTab = uitab(app.TabGroup);
            app.SetpSettingsTab.Tooltip = {''};
            app.SetpSettingsTab.Title = 'Setpoint Settings';
            app.SetpPanel = uipanel(app.SetpSettingsTab);
            app.SetpPanel.Tooltip = {'Load data set to change settings.'};
            app.SetpPanel.Title = 'Setpoint r';
            app.SetpButtonGroup = uibuttongroup(app.SetpPanel);
            app.SetpButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetpButtonGroupSelectionChanged, true);
            app.SetpButtonGroup.Tooltip = {'Load data set to change settings.'};
            app.SetpButtonGroup.Title = 'Would you like to specify setpoint?';
            app.NoSetpButton = uiradiobutton(app.SetpButtonGroup);
            app.NoSetpButton.Enable = 'off';
            app.NoSetpButton.Text = 'r = y';
            app.NoSetpButton.Value = true;
            app.YesSetpButton = uiradiobutton(app.SetpButtonGroup);
            app.YesSetpButton.Enable = 'off';
            app.YesSetpButton.Tooltip = {'Load data set to change settings.'; 'Select variable from the drop down list below.'};
            app.YesSetpButton.Text = 'Select setpoint';
            app.SelectSetpDropDownLabel = uilabel(app.SetpPanel);
            app.SelectSetpDropDownLabel.Text = 'Select variable';
            app.SelectSetpDropDown = uidropdown(app.SetpPanel);
            app.SelectSetpDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectSetpDropDownOpening, true);
            app.SelectSetpDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSetpDropDownValueChanged, true);
            app.SelectSetpDropDown.Enable = 'off';
            app.SelectSetpDropDown.Tooltip = {'Load data set to change settings.'; 'Select "Select setpoint" option above.'};
            app.SetpConstraintsPanel = uipanel(app.SetpSettingsTab);
            app.SetpConstraintsPanel.Tooltip = {'Load data set to change settings.'; 'This panel is currently under development.'};
            app.SetpConstraintsPanel.Title = 'Setpoint constraints';
            app.SetpLimValPanel = uipanel(app.SetpConstraintsPanel);
            app.SetpLimValPanel.Tooltip = {'Select input, output, setpoint, time interval and plot the original data to change settings.'; 'This panel is currently under development.'};
            app.SetpLimValPanel.Title = 'Limits on value';
            app.LowerSetpLimValEditFieldLabel = uilabel(app.SetpLimValPanel);
            app.LowerSetpLimValEditFieldLabel.Text = 'Lower';
            app.LowerSetpLimValEditField = uieditfield(app.SetpLimValPanel, 'numeric');
            app.LowerSetpLimValEditField.ValueChangedFcn = createCallbackFcn(app, @LowerSetpLimValEditFieldValueChanged, true);
            app.LowerSetpLimValEditField.Enable = 'off';
            app.LowerSetpLimValEditField.Tooltip = {'Select input, output, setpoint, time interval and plot the original data to change settings.'};
            app.UpperSetpLimValEditFieldLabel = uilabel(app.SetpLimValPanel);
            app.UpperSetpLimValEditFieldLabel.Text = 'Upper';
            app.UpperSetpLimValEditField = uieditfield(app.SetpLimValPanel, 'numeric');
            app.UpperSetpLimValEditField.ValueChangedFcn = createCallbackFcn(app, @UpperSetpLimValEditFieldValueChanged, true);
            app.UpperSetpLimValEditField.Enable = 'off';
            app.UpperSetpLimValEditField.Tooltip = {'Select input, output, setpoint, time interval and plot the original data to change settings.'};
            app.SelectValueLimits_2 = uicheckbox(app.SetpLimValPanel);
            app.SelectValueLimits_2.ValueChangedFcn = createCallbackFcn(app, @SelectValueLimits_2ValueChanged, true);
            app.SelectValueLimits_2.Text = 'Click to Select';
            app.SetpCritValPanel = uipanel(app.SetpConstraintsPanel);
            app.SetpCritValPanel.Tooltip = {'This panel is currently under development.'};
            app.SetpCritValPanel.Title = 'Critical values';
            app.SetpCritValEditField = uieditfield(app.SetpCritValPanel, 'numeric');
            app.SetpCritValEditField.Enable = 'off';
            app.SelectValueLimits_4 = uicheckbox(app.SetpCritValPanel);
            app.SelectValueLimits_4.ValueChangedFcn = createCallbackFcn(app, @SelectValueLimits_4ValueChanged, true);
            app.SelectValueLimits_4.Text = 'Click to Select';
            app.SetpLimVarPanel = uipanel(app.SetpConstraintsPanel);
            app.SetpLimVarPanel.Tooltip = {'This panel is currently under development.'};
            app.SetpLimVarPanel.Title = 'Limits on variance';
            app.LowerSetpLimVarEditFieldLabel = uilabel(app.SetpLimVarPanel);
            app.LowerSetpLimVarEditFieldLabel.Text = 'Lower';
            app.LowerSetpLimVarEditField = uieditfield(app.SetpLimVarPanel, 'numeric');
            app.LowerSetpLimVarEditField.Enable = 'off';
            app.UpperSetpLimVarEditFieldLabel = uilabel(app.SetpLimVarPanel);
            app.UpperSetpLimVarEditFieldLabel.Text = 'Upper';
            app.UpperSetpLimVarEditField = uieditfield(app.SetpLimVarPanel, 'numeric');
            app.UpperSetpLimVarEditField.Enable = 'off';
            app.SetpmovvarkEditFieldLabel = uilabel(app.SetpLimVarPanel);
            app.SetpmovvarkEditFieldLabel.Text = 'Length of window';
            app.SetpmovvarkEditField = uieditfield(app.SetpLimVarPanel, 'numeric');
            app.SetpmovvarkEditField.Enable = 'off';
            app.SelectValueLimits_3 = uicheckbox(app.SetpLimVarPanel);
            app.SelectValueLimits_3.ValueChangedFcn = createCallbackFcn(app, @SelectValueLimits_3ValueChanged, true);
            app.SelectValueLimits_3.Text = 'Click to Select';
            app.AddSetpConstraintsPanel = uipanel(app.SetpConstraintsPanel);
            app.AddSetpConstraintsPanel.Tooltip = {'This panel is currently under development.'};
            app.AddSetpConstraintsPanel.Title = 'Additional constraints';
            app.SetpAbruptChangeCheckBox = uicheckbox(app.AddSetpConstraintsPanel);
            app.SetpAbruptChangeCheckBox.Enable = 'off';
            app.SetpAbruptChangeCheckBox.Text = 'Abrupt change';
            app.SetpFreezeCheckBox = uicheckbox(app.AddSetpConstraintsPanel);
            app.SetpFreezeCheckBox.Enable = 'off';
            app.SetpFreezeCheckBox.Text = 'Freeze';
            app.SetpSaturatedloopCheckBox = uicheckbox(app.AddSetpConstraintsPanel);
            app.SetpSaturatedloopCheckBox.Enable = 'off';
            app.SetpSaturatedloopCheckBox.Text = 'Saturated loop';
            app.SelectValueLimits_5 = uicheckbox(app.AddSetpConstraintsPanel);
            app.SelectValueLimits_5.ValueChangedFcn = createCallbackFcn(app, @SelectValueLimits_5ValueChanged, true);
            app.SelectValueLimits_5.Text = 'Click to Select';
            app.ImplementConstraintsButton_2 = uibutton(app.SetpConstraintsPanel, 'push');
            app.ImplementConstraintsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImplementConstraintsButton_2Pushed, true);
            app.ImplementConstraintsButton_2.Text = 'Implement Constraints';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Tooltip = {'Load data set to change the parameter values.'};
            app.ParametersTab.Title = 'Parameters';
            app.LaguerreParametersPanel = uipanel(app.ParametersTab);
            app.LaguerreParametersPanel.Tooltip = {'Parameters for Laguerre based approach.'};
            app.LaguerreParametersPanel.Title = 'Free parameter values of the Laguerre model';
            app.alpha1EditFieldLabel = uilabel(app.LaguerreParametersPanel);
            app.alpha1EditFieldLabel.Text = 'α_1';
            app.alpha1EditField = uieditfield(app.LaguerreParametersPanel, 'numeric');
            app.alpha1EditField.Limits = [0 1];
            app.alpha1EditField.ValueChangedFcn = createCallbackFcn(app, @alpha1EditFieldValueChanged, true);
            app.alpha1EditField.Editable = 'off';
            app.alpha1EditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.alpha1EditField.Value = 0.8;
            app.NisEditFieldLabel = uilabel(app.LaguerreParametersPanel);
            app.NisEditFieldLabel.Text = 'N_is';
            app.NisEditField = uieditfield(app.LaguerreParametersPanel, 'numeric');
            app.NisEditField.Limits = [0 Inf];
            app.NisEditField.ValueChangedFcn = createCallbackFcn(app, @NisEditFieldValueChanged, true);
            app.NisEditField.Editable = 'off';
            app.NisEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter positive value.'};
            app.NisEditField.Value = 6;
            app.DefaultsLaguerreButton = uibutton(app.LaguerreParametersPanel, 'push');
            app.DefaultsLaguerreButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultsLaguerreButtonPushed, true);
            app.DefaultsLaguerreButton.Enable = 'off';
            app.DefaultsLaguerreButton.Tooltip = {'Restore default parameter values if they were changed manually.'};
            app.DefaultsLaguerreButton.Text = 'Restore default values';
            app.ForgettingParametersPanel = uipanel(app.ParametersTab);
            app.ForgettingParametersPanel.Tooltip = {'Parameters for Yuri''s approach.'};
            app.ForgettingParametersPanel.Title = 'Thresholds and forgetting values';
            app.lambdanyEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdanyEditFieldLabel.Text = 'λ_ny';
            app.lambdanyEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdanyEditField.Limits = [0 1];
            app.lambdanyEditField.ValueChangedFcn = createCallbackFcn(app, @lambdanyEditFieldValueChanged, true);
            app.lambdanyEditField.Editable = 'off';
            app.lambdanyEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdanyEditField.Value = 0.99;
            app.lambdamLEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdamLEditFieldLabel.Text = 'λ_mL';
            app.lambdamLEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdamLEditField.Limits = [0 1];
            app.lambdamLEditField.ValueChangedFcn = createCallbackFcn(app, @lambdamLEditFieldValueChanged, true);
            app.lambdamLEditField.Editable = 'off';
            app.lambdamLEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdamLEditField.Value = 0.99;
            app.lambdanLEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdanLEditFieldLabel.Text = 'λ_nL';
            app.lambdanLEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdanLEditField.Limits = [0 1];
            app.lambdanLEditField.ValueChangedFcn = createCallbackFcn(app, @lambdanLEditFieldValueChanged, true);
            app.lambdanLEditField.Editable = 'off';
            app.lambdanLEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdanLEditField.Value = 0.99;
            app.lambdamuEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdamuEditFieldLabel.Text = 'λ_mu';
            app.lambdamuEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdamuEditField.Limits = [0 1];
            app.lambdamuEditField.ValueChangedFcn = createCallbackFcn(app, @lambdamuEditFieldValueChanged, true);
            app.lambdamuEditField.Editable = 'off';
            app.lambdamuEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdamuEditField.Value = 0.99;
            app.lambdanuEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdanuEditFieldLabel.Text = 'λ_nu';
            app.lambdanuEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdanuEditField.Limits = [0 1];
            app.lambdanuEditField.ValueChangedFcn = createCallbackFcn(app, @lambdanuEditFieldValueChanged, true);
            app.lambdanuEditField.Editable = 'off';
            app.lambdanuEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdanuEditField.Value = 0.99;
            app.etaL1Label = uilabel(app.ForgettingParametersPanel);
            app.etaL1Label.Text = 'η_L1';
            app.etaL1EditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.etaL1EditField.Limits = [0 1];
            app.etaL1EditField.ValueChangedFcn = createCallbackFcn(app, @etaL1EditFieldValueChanged, true);
            app.etaL1EditField.Editable = 'off';
            app.etaL1EditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.etaL1EditField.Value = 1e-07;
            app.etaYEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.etaYEditFieldLabel.Text = 'η_Y';
            app.etaYEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.etaYEditField.Limits = [0 1];
            app.etaYEditField.ValueChangedFcn = createCallbackFcn(app, @etaYEditFieldValueChanged, true);
            app.etaYEditField.Editable = 'off';
            app.etaYEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.etaYEditField.Value = 1e-10;
            app.etaCondEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.etaCondEditFieldLabel.Text = 'η_Cond';
            app.etaCondEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.etaCondEditField.ValueChangedFcn = createCallbackFcn(app, @etaCondEditFieldValueChanged, true);
            app.etaCondEditField.Editable = 'off';
            app.etaCondEditField.Tooltip = {'Load data set to change the parameter values.'};
            app.etaCondEditField.Value = 1000000;
            app.lambdaREditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdaREditFieldLabel.Text = 'λ_R';
            app.lambdaREditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdaREditField.Limits = [0 1];
            app.lambdaREditField.ValueChangedFcn = createCallbackFcn(app, @lambdaREditFieldValueChanged, true);
            app.lambdaREditField.Editable = 'off';
            app.lambdaREditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdaREditField.Value = 0.95;
            app.etaChiEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.etaChiEditFieldLabel.Text = 'η_χ';
            app.etaChiEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.etaChiEditField.ValueChangedFcn = createCallbackFcn(app, @etaChiEditFieldValueChanged, true);
            app.etaChiEditField.Editable = 'off';
            app.etaChiEditField.Tooltip = {'Load data set to change the parameter values.'};
            app.etaChiEditField.Value = 1000;
            app.lambdamyEditFieldLabel = uilabel(app.ForgettingParametersPanel);
            app.lambdamyEditFieldLabel.Text = 'λ_my';
            app.lambdamyEditField = uieditfield(app.ForgettingParametersPanel, 'numeric');
            app.lambdamyEditField.Limits = [0 1];
            app.lambdamyEditField.ValueChangedFcn = createCallbackFcn(app, @lambdamyEditFieldValueChanged, true);
            app.lambdamyEditField.Editable = 'off';
            app.lambdamyEditField.Tooltip = {'Load data set to change the parameter values.'; 'Enter the value between 0 and 1.'};
            app.lambdamyEditField.Value = 0.99;
            app.DefaultsForgettingButton = uibutton(app.ForgettingParametersPanel, 'push');
            app.DefaultsForgettingButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultsForgettingButtonPushed, true);
            app.DefaultsForgettingButton.Enable = 'off';
            app.DefaultsForgettingButton.Tooltip = {'Restore default parameter values if they were changed manually.'};
            app.DefaultsForgettingButton.Text = 'Restore default values';
            % Show the figure after all components are created
            app.DataQualityAssessmentApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DQGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataQualityAssessmentApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataQualityAssessmentApplicationUIFigure)
        end
    end
end